classdef Team1_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ResultButton                    matlab.ui.control.Button
        ClosingButton                   matlab.ui.control.Button
        BwareaopenButton                matlab.ui.control.Button
        ExtraxtsilverstripsButton       matlab.ui.control.Button
        RGBtoHSIButton                  matlab.ui.control.Button
        ExtracttransparentGandhiButton  matlab.ui.control.Button
        ReadImageButton                 matlab.ui.control.Button
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ReadImageButton
        function ReadImageButtonPushed(app, event)
            global org;
            global A;
            [file,path]=uigetfile('*.*');
            filename=strcat(path,file);
            org=imread(filename);
            A = rgb2gray(org);
            imshow(A,'parent',app.UIAxes);
            global test;
            global B;
            [file,path]=uigetfile('*.*');
            filename=strcat(path,file);
            test=imread(filename);
            B = rgb2gray(test);
            imshow(B,'parent',app.UIAxes2);
        end

        % Callback function
        function ReadFakeNoteButton_2Pushed(app, event)
            global test;
            global B;
            [file,path]=uigetfile('*.*');
            filename=strcat(path,file);
            test=imread(filename);
            B = rgb2gray(test);
            imshow(B,'parent',app.UIAxes2);
        end

        % Button pushed function: ExtracttransparentGandhiButton
        function ExtracttransparentGandhiButtonPushed(app, event)
           global A;
           global B;
           global Ag;
           global Bg;
           %[Ag,rect] = imcrop(A);
           Ag = imcrop(A,[2218.5 204.5 535 521]); 
           imshow(Ag,'parent',app.UIAxes)                                          %transparent gandhi 
           %[Bg,rect] = imcrop(B);
           Bg = imcrop(B,[2218.5 204.5 535 521]); 
           imshow(Bg,'parent',app.UIAxes2)
        end

        % Button pushed function: RGBtoHSIButton
        function RGBtoHSIButtonPushed(app, event)
            global org;
            global test;
            global Ahsv;
            global Bhsv;
            Ahsv = rgb2hsv(org);
            h1 = Ahsv(:, :, 1);% Hue image.
            s1 = Ahsv(:, :, 2); % Saturation image.
            v1 = Ahsv(:, :, 3); % Value (intensity) image.
            imshow(Ahsv,'parent',app.UIAxes)
            
            Bhsv = rgb2hsv(test);
            h2 = Bhsv(:, :, 1); % Hue image.
            s2 = Bhsv(:, :, 2); % Saturation image.
            v2 = Bhsv(:, :, 3); % Value (intensity) image.
            imshow(Bhsv,'parent',app.UIAxes2)
        end

        % Button pushed function: ExtraxtsilverstripsButton
        function ExtraxtsilverstripsButtonPushed(app, event)
            global Ahsv;
            global Bhsv;
            global Abinary;
            global Bbinary;
            %[Acrop,rect] = imcrop(Ahsv);
            Acrop = imcrop(Ahsv,[1778.5 13.5 57 963]);
            %[Bcrop,rect] = imcrop(Bhsv);
            Bcrop = imcrop(Bhsv,[1778.5 13.5 57 963]);
            sat = 0.3;
            val = 0.9;
            Abinary = (Acrop(:,:,2) > sat & Acrop(:,:,3) < val);
            imshow(Abinary,'parent',app.UIAxes);
            Bbinary= (Bcrop(:,:,2) > sat & Bcrop(:,:,3) < val);
            imshow(Bbinary,'parent',app.UIAxes2);
        end

        % Button pushed function: ClosingButton
        function ClosingButtonPushed(app, event)
            global Abinary;
            global Bbinary;   
            global Aclosing;
            global Bclosing;
            se = strel('line', 200, 90);
            Aclosing = imclose(Abinary, se);
            Bclosing = imclose(Bbinary, se);
            imshow(Aclosing,'parent',app.UIAxes);
            imshow(Bclosing,'parent',app.UIAxes2);
        end

        % Button pushed function: BwareaopenButton
        function BwareaopenButtonPushed(app, event)
            global Aclosing;
            global Bclosing;
            global Aopen;
            global Bopen;
            Aopen = bwareaopen(Aclosing, 15);
            imshow(Aopen,'parent',app.UIAxes);
            Bopen = bwareaopen(Bclosing, 15);
            imshow(Bopen,'parent',app.UIAxes2);
        end

        % Button pushed function: ResultButton
        function ResultButtonPushed(app, event)
            %count black lines
            global Aopen;
            global Bopen;
            global Ag;
            global Bg;
            [~,countReal] = bwlabel(Aopen);
            [~,countFake] = bwlabel(Bopen);
            disp(['The total number of  Regions for the real note is: ' num2str(countReal)]);
            disp(['The total number of  Regions for the fake note is: ' num2str(countFake)]);
            
            co=corr2(Ag,Bg); 
            
            %display of conclusion
            
            if(co>=0.5 && countReal == countFake)
                message=sprintf('correlevance of transparent gandhi > 0.5. So, both notes are Original');
                uiwait(msgbox(message));
                
            else
                message2=sprintf('correlevance of transparent gandhi < 0.5. So, currency is fake');
                uiwait(msgbox(message2));
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Original Currency note')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = '';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Duplicate Currency note')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.ReadImageButton = uibutton(app.UIFigure, 'push');
            app.ReadImageButton.ButtonPushedFcn = createCallbackFcn(app, @ReadImageButtonPushed, true);
            app.ReadImageButton.Text = 'Read Image';
            app.ExtracttransparentGandhiButton = uibutton(app.UIFigure, 'push');
            app.ExtracttransparentGandhiButton.ButtonPushedFcn = createCallbackFcn(app, @ExtracttransparentGandhiButtonPushed, true);
            app.ExtracttransparentGandhiButton.Text = 'Extract transparent Gandhi';
            app.RGBtoHSIButton = uibutton(app.UIFigure, 'push');
            app.RGBtoHSIButton.ButtonPushedFcn = createCallbackFcn(app, @RGBtoHSIButtonPushed, true);
            app.RGBtoHSIButton.Text = 'RGB to HSI';
            app.ExtraxtsilverstripsButton = uibutton(app.UIFigure, 'push');
            app.ExtraxtsilverstripsButton.ButtonPushedFcn = createCallbackFcn(app, @ExtraxtsilverstripsButtonPushed, true);
            app.ExtraxtsilverstripsButton.Text = 'Extraxt silver strips';
            app.BwareaopenButton = uibutton(app.UIFigure, 'push');
            app.BwareaopenButton.ButtonPushedFcn = createCallbackFcn(app, @BwareaopenButtonPushed, true);
            app.BwareaopenButton.Text = 'Bwareaopen';
            app.ClosingButton = uibutton(app.UIFigure, 'push');
            app.ClosingButton.ButtonPushedFcn = createCallbackFcn(app, @ClosingButtonPushed, true);
            app.ClosingButton.Text = 'Closing';
            app.ResultButton = uibutton(app.UIFigure, 'push');
            app.ResultButton.ButtonPushedFcn = createCallbackFcn(app, @ResultButtonPushed, true);
            app.ResultButton.Text = 'Result';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Team1_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end